classdef siteInteractionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DispBotVideoButton        matlab.ui.control.Button
        DispTopVideoButton        matlab.ui.control.Button
        SaveAnnotatedDataButton   matlab.ui.control.Button
        MaskAllCheckBox           matlab.ui.control.CheckBox
        MaskFlagsButton           matlab.ui.control.StateButton
        PostPredSpinner           matlab.ui.control.Spinner
        PostPredSpinnerLabel      matlab.ui.control.Label
        PrePredSpinner            matlab.ui.control.Spinner
        PrePredSpinnerLabel       matlab.ui.control.Label
        ShowInitImagesButton      matlab.ui.control.Button
        InitialCountSpinner       matlab.ui.control.Spinner
        InitialCountSpinnerLabel  matlab.ui.control.Label
        SeedChangeSpinner         matlab.ui.control.Spinner
        SeedChangeSpinnerLabel    matlab.ui.control.Label
        NavbyButtonGroup          matlab.ui.container.ButtonGroup
        FlagButton                matlab.ui.control.ToggleButton
        SiteButton                matlab.ui.control.ToggleButton
        InteractionButton         matlab.ui.control.ToggleButton
        botCamImage               matlab.ui.control.Image
        SeedChangeLabel           matlab.ui.control.Label
        FlagLabel                 matlab.ui.control.Label
        InteractionSlider         matlab.ui.control.Slider
        VisitSliderLabel          matlab.ui.control.Label
        seedCountAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        seedStruct % Keeps track of variables for seed tracking
        nInteractions % convenience
        nInt % convenience
        nSite % convenience
        newPrePreds = []; % indices for any prePreds that have been changed from initial values
        newPostPreds = []; % indices for any postPreds that have been changed from initial values
        flagMask % visits to ignore any flags for
        topVid % top video for displaying caching events
        botVid % bottom video for displaying caching events
        cacheNet % cache NN used to predict site occupancy
        startTimer % Timer counting from app startup (used for loading time and refresh rate calculations)
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            siteCounts = cumsum(app.seedStruct.seedChanges(:,app.nSite)) + app.seedStruct.initSeedCounts(app.nSite);
            seedChange = app.seedStruct.seedChanges(app.nInt,app.nSite);
            isSiteInt = app.seedStruct.cacheNum==app.nSite;
            hasFlag = sum(~cellfun(@isempty, app.seedStruct.flagInt(isSiteInt))) > 1/2;
            
            % Update Slider
            app.VisitSliderLabel.Text = sprintf('Interaction %d; Site %d', app.nInt, app.nSite);
            if hasFlag
                app.VisitSliderLabel.FontColor = [0.85,0.33,0.10];
            else
                app.VisitSliderLabel.FontColor = [0,0,0];
            end
            
            if app.InteractionButton.Value
                app.InteractionSlider.MinorTicks = 25:25:app.nInteractions;
            elseif app.SiteButton.Value
                app.InteractionSlider.MinorTicks = find(isSiteInt);
            elseif app.FlagButton.Value
                app.InteractionSlider.MinorTicks = find(~cellfun(@isempty, app.seedStruct.flagInt));
            end
            
            % update flag and seedChange display
            app.FlagLabel.Text = sprintf('Flag: %s', app.seedStruct.flagInt{app.nInt});
            app.SeedChangeLabel.Text = sprintf('Seeds Before: %d; Seeds After: %d', siteCounts(app.nInt)-seedChange, siteCounts(app.nInt));
            app.SeedChangeSpinner.Value = seedChange;
            
            % update bottom camera display
            thisImg = imfuse(app.seedStruct.preIms(:,:,app.nInt), app.seedStruct.postIms(:,:,app.nInt));
            app.botCamImage.ImageSource = thisImg;
            
            % update seed count plot
            plot(app.seedCountAxes, siteCounts, 'k', 'linewidth', 1),
            ay = ylim(app.seedCountAxes);
            if hasFlag
                siteFlags = find(~cellfun(@isempty, app.seedStruct.flagInt(isSiteInt)));
                siteInts = find(isSiteInt);
                hold(app.seedCountAxes,'on'),
                for nFlag = 1:length(siteFlags)
                    nInt = siteInts(siteFlags(nFlag));
%                     line(app.seedCountAxes, nInt*[1,1], ay, 'Color', [0, 0.45, 0.75], 'linewidth', 1, 'linestyle', ':');
                    plot(app.seedCountAxes,nInt, siteCounts(nInt),'r*'),
                end
                hold(app.seedCountAxes,'off'),
            end
            line(app.seedCountAxes, app.nInt * [1,1], ay, 'Color', [0.85, 0.4, 0.1], 'linewidth', 2);
            
            % update spinners
            app.InitialCountSpinner.Value = app.seedStruct.initSeedCounts(app.nSite);
            app.PrePredSpinner.Value = app.seedStruct.prePreds(app.nInt);
            app.PostPredSpinner.Value = app.seedStruct.postPreds(app.nInt);
            
            % update flagMask status button
            app.MaskFlagsButton.Value = app.flagMask(app.nInt);
            
            %
            drawnow limitrate nocallbacks,
        end
        
        function updateFlags(app)
            allInteractions = find(app.seedStruct.cacheNum==app.nSite)';
            seedChanges = app.seedStruct.seedChanges;
            flagInt = app.seedStruct.flagInt;
            for thisInt = allInteractions
                % skip flag checks for this visit if masked
                if app.flagMask(thisInt)
                    continue
                end
                flagInt{thisInt} = [];
                preSeedCount = sum(seedChanges(1:thisInt-1, app.nSite)) + app.seedStruct.initSeedCounts(app.nSite);
                thisChange = seedChanges(thisInt, app.nSite);
                postSeedCount = preSeedCount + thisChange;
                prePred = app.seedStruct.prePreds(thisInt);
                postPred = app.seedStruct.postPreds(thisInt);
                
                if (postSeedCount < 0) && (thisChange < 0) % cannot have negative seeds!
                    % check if site is empty
                    if prePred < 1/2 && postPred < 1/2
                        % the site is empty before and after the visit
                        flagInt{thisInt} = sprintf('Retrieval from empty site %d',...
                            app.nSite);
                    else
                        % Unclear why this would happen, requires manual check
                        flagInt{thisInt} = sprintf('Uninterpretable seed change on interaction %d', thisInt);
                    end
                elseif (postPred < 1/2) && (thisChange > 1/2)
                    % this site is not occupied post visit
                    flagInt{thisInt} = sprintf('Cache to site %d  not confirmed in bot cam',...
                        app.nSite);
                end
                
                % don't overwrite a flag from above
                if isempty(flagInt{thisInt})
                    if (postSeedCount > 1/2) ~= (postPred > 1/2)
                        flagInt{thisInt} = sprintf('Site %d has post-count %d but botCam predicts %0.2f',...
                            app.nSite, postSeedCount, postPred);
                    elseif (preSeedCount > 1/2) ~= (prePred > 1/2)
                        flagInt{thisInt} = sprintf('Site %d has pre-count %d but botCam predicts %0.2f',...
                            app.nSite, preSeedCount, prePred);
                    end
                end
            end
            
            app.seedStruct.flagInt = flagInt;
        end
        
        function seedStruct = getSeedTimes(~,seedStruct)
            smSeed = seedStruct.smSeed;
            gainThresh = seedStruct.gainThresh;
            loseThresh = seedStruct.loseThresh;
            minLoseDur = seedStruct.minLoseDur;
            
            seedTimes = [];
            % detect all gains and record gain/loss times
            while true
                gainTime = find(smSeed > gainThresh, 1);
                if isempty(gainTime)
                    break
                end
                loseTime = find(smSeed(gainTime+1:end) < loseThresh, 1) + gainTime;
                if isempty(loseTime)
                    loseTime = length(smSeed);
                end
                
                % find previous frame with definite loss or gain
                lastLoseTime = find(smSeed(1:gainTime) < loseThresh, 1, 'last');
                if isempty(lastLoseTime)
                    lastLoseTime = gainTime; % this occurs only if recording starts w/ seed in beak
                end
                lastGainTime = find(smSeed(1:loseTime) > gainThresh, 1, 'last');
                
                smSeed(gainTime:lastGainTime)=nan; % remove these gain-frames from search
                seedTimes(end+1,1:4) = [lastLoseTime, gainTime, lastGainTime, loseTime]-1; % corrected for Pythonic inputs 
            end
            
            while true
                % find gains following suspiciously short losses
                falseLose = find((seedTimes(2:end,2) - seedTimes(1:end-1,4)) < minLoseDur, 1);
                if isempty(falseLose)
                    break
                end
                % extend the seed event duration to the end of next event
                seedTimes(falseLose,3:4) = seedTimes(falseLose+1,3:4);
                % eliminate the next event
                seedTimes(falseLose+1,:) = [];
            end
            
            seedStruct.seedTimes = seedTimes;
            seedStruct.nEvents = size(seedTimes,1);
            fprintf('found %d seed carrying events\n',seedStruct.nEvents),
        end
        
        function seedStruct = processSiteInteractions(~,seedStruct)
            % input params
            seedIntTol = seedStruct.seedIntTol;
            seedTimes = seedStruct.seedTimes;
            newCacheTimes = seedStruct.countData.newSite;
            endCacheTimes = seedStruct.countData.endSite;
            cacheNum = seedStruct.countData.siteNum;

            % find gains or losses to cache sites
            % code looks for overlap, i.e. end time is after start, start
            % time is before end, and make sure each event is assigned to the last interaction
            gainMat = false(length(newCacheTimes), size(seedTimes,1));
            loseMat = false(length(newCacheTimes), size(seedTimes,1));
            for nEvent = 1:size(seedTimes,1)
                gainInt = find((endCacheTimes+seedIntTol>=seedTimes(nEvent,1)) & ...
                    (newCacheTimes-seedIntTol<=seedTimes(nEvent,2)), 1, 'last');
                if ~isempty(gainInt)
                    gainMat(gainInt,nEvent) = true;
                end
                loseInt = find((endCacheTimes+seedIntTol>=seedTimes(nEvent,3)) & ...
                    (newCacheTimes-seedIntTol<=seedTimes(nEvent,4)), 1, 'last');
                if ~isempty(loseInt)
                    loseMat(loseInt,nEvent) = true;
                end
            end
            
            [gainInt, gainEvent] = find(gainMat);
            gainSite = cacheNum(gainInt);
            [loseInt, loseEvent] = find(loseMat);
            loseSite = cacheNum(loseInt);
                    
            % distribute results to binary arrays
            nEvents = size(seedTimes,1);
            nInteractions = length(endCacheTimes);
            nSites = size(seedStruct.cacheLoc,1);
            % note, this code cannot handle multiple gains or losses for a
            % single site interaction
            fromCache_site = full(sparse(gainInt, gainSite, 1, nInteractions, nSites));
            fromCache_event = full(sparse(gainEvent, gainSite, 1, nEvents, nSites));
            toCache_site = full(sparse(loseInt, loseSite, 1, nInteractions, nSites));
            toCache_event = full(sparse(loseEvent, loseSite, 1, nEvents, nSites));
            
            % error check
            % TODO: add an error check for if any site check has multiple gains
            % or losses, and warn that these are not tracked (maybe list
            % the site int # so it can be manually examined?)
            if sum(any(fromCache_site,2) & any(toCache_site,2))
                fprintf('%d Site Interactions contains gain and loss \n',...
                    sum(any(fromCache_site,2) & any(toCache_site,2)));
            end
            
            % record results
            seedStruct.newCacheTimes = newCacheTimes;
            seedStruct.endCacheTimes = endCacheTimes;
            seedStruct.cacheNum = cacheNum;
            seedStruct.fromCache_site = fromCache_site;
            seedStruct.fromCache_event = fromCache_event;
            seedStruct.toCache_site = toCache_site;
            seedStruct.toCache_event = toCache_event;
            seedStruct.gainMat = sparse(gainMat);
            seedStruct.loseMat = sparse(loseMat);
        end
        
        function seedStruct = getStartingImages(~,seedStruct, botVid)
            site_hw = 15;
            site_w = site_hw*2 + 1;
            cacheLoc = seedStruct.cacheLoc;    
            nFramesAvg = 30;
            botVid.CurrentTime = 0;
            initImgs = nan(site_w,site_w,size(cacheLoc,1),'single');
            tmpFrames = nan(botVid.Height, botVid.Width, nFramesAvg, 'single');
            for f = 1:nFramesAvg
                tmpFrame = botVid.readFrame;
                tmpFrames(:,:,f) = tmpFrame(:,:,1);
            end
            tmpImg = mean(tmpFrames,3);
        
            for nCache = 1:size(cacheLoc,1)
                thisCent = round(seedStruct.cacheLoc(nCache,:));
                thisY = thisCent(2) + (-site_hw:site_hw); % adj from SC +-25
                thisX = thisCent(1) + (-site_hw:site_hw); % adj from SC +-25
                initImgs(:,:,nCache) = tmpImg(thisY,thisX);
            end
            seedStruct.initImgs = initImgs/255;
        end
        
        function seedStruct = getInteractionImages(~,seedStruct, botVid)
            
            fps = 50; % frames per second
            site_hw = 15; % halfwidth of cache image
            site_w = site_hw*2 + 1;
            nFramesAvg = 3; % # of frames to average together
            nFramesBuff = 15; % # of frames to buffer before/after cacheTimes for grabbing botCam image
            minSecBuff = nFramesBuff/fps + nFramesAvg/fps/2; % time in seconds needed before interaction onset or after offset
            newCacheTimes = seedStruct.newCacheTimes;
            endCacheTimes = seedStruct.endCacheTimes;
            cacheNum = seedStruct.cacheNum;
            nInteractions = length(newCacheTimes);          
            tmpFrames = nan(botVid.Height,botVid.Width,nFramesAvg,'single');
            preIms = nan(site_w,site_w,nInteractions,'single');
            postIms = nan(site_w,site_w,nInteractions,'single');
            cacheLoc = seedStruct.cacheLoc;
            
            % Get image for each pre/post interaction
            for nInteraction = 1:nInteractions
                if ~mod(nInteraction,400)
                    fprintf('Processing Interaction %d; \n',nInteraction),
                end
                
                thisSite = cacheNum(nInteraction);
                startTime = newCacheTimes(nInteraction)/fps - minSecBuff;
                endTime = endCacheTimes(nInteraction)/fps + minSecBuff;
                if endTime > botVid.Duration
                    fprintf('Bot Video stopped before end of interaction %d \n',nInteraction),
                    continue
                elseif startTime < 0
                    fprintf('Bot Video started after start of interaction %d \n',nInteraction),
                    continue
                end
                
                % get image before interaction
                botVid.CurrentTime = startTime;
                for f=1:nFramesAvg
                    thisFrame = botVid.readFrame;
                    tmpFrames(:,:,f) = thisFrame(:,:,1);
                end
                tmpImg = mean(tmpFrames,3);
                thisCent = round(cacheLoc(thisSite,:));
                thisY = thisCent(2) + (-site_hw:site_hw);
                thisX = thisCent(1) + (-site_hw:site_hw);
                preIms(:,:,nInteraction) = tmpImg(thisY,thisX);
                
                % use same image at different site for 'post' image
                if nInteraction>1
                    lastSite = cacheNum(nInteraction-1);
                    thisCent = round(cacheLoc(lastSite,:));
                    thisY = thisCent(2) + (-site_hw:site_hw);
                    thisX = thisCent(1) + (-site_hw:site_hw);
                    postIms(:,:,nInteraction-1) = tmpImg(thisY,thisX);
                end
               
                %get image after last interaction
                if nInteraction==nInteractions
                    botVid.CurrentTime = endTime - nFramesAvg/fps/2;
                    for f=1:nFramesAvg
                        thisFrame = botVid.readFrame;
                        tmpFrames(:,:,f) = thisFrame(:,:,1);
                    end
                    tmpImg = mean(tmpFrames,3);
                    thisCent = round(cacheLoc(thisSite,:));
                    thisY = thisCent(2) + (-site_hw:site_hw);
                    thisX = thisCent(1) + (-site_hw:site_hw);
                    postIms(:,:,nInteraction) = tmpImg(thisY,thisX);
                end
            end
                    
            seedStruct.preIms = double(preIms)/255;
            seedStruct.postIms = double(postIms)/255;
        end
        
        function seedStruct = processBotCamImgs(~,seedStruct, cacheNet)
            % define seed 'changes' as difference between gains and
            % removals from each site
            site_hw = 15;
            site_w = site_hw*2 + 1;
            seedChanges = seedStruct.toCache_site-seedStruct.fromCache_site;
            seedCount = cumsum(seedChanges);
            nInteractions = size(seedChanges,1);
                        
            % get predictions from bot camera images
            initPreds = cacheNet.predict(reshape(seedStruct.initImgs,site_w,site_w,1,[]));
            initPreds = initPreds(:,2);
            initSeedCounts = double(initPreds > 1/2); %assume 1 seed in any occupied site
            fprintf('Found %d sites occupied at experiment start \n', sum(initSeedCounts)),
            prePreds = cacheNet.predict(reshape(seedStruct.preIms,site_w,site_w,1,[]));
            prePreds = prePreds(:,2);
            postPreds = cacheNet.predict(reshape(seedStruct.postIms,site_w,site_w,1,[]));
            postPreds = postPreds(:,2);
            
            % create cell array with flagged event info
            flagInt = cell(nInteractions,1);
            for nInt = 1:nInteractions
                % parse info for this perch visit
                siteNum = seedStruct.cacheNum(nInt);
                if nInt==1
                    preSeedCount = 0;
                else
                    preSeedCount = seedCount(nInt-1, siteNum);
                end
                thisChange = seedChanges(nInt, siteNum);
                postSeedCount = seedCount(nInt, siteNum);
                prePred = prePreds(nInt);
                postPred = postPreds(nInt);              
                
                preCheck = (preSeedCount>0) == (prePred>1/2);
                postCheck = (postSeedCount>0) == (postPred>1/2);
                
                % Handle initially occupied sites (TODO: SHOULD MANUALLY VERIFY)
                if initPreds(siteNum)>1/2 & (~preCheck | ~postCheck)
                    flagInt{nInt} = 'Botcam check failing for initially occupied site';
                end
                
                % Handle retrievals from or caches to above flap
                if (postSeedCount < 0) && (thisChange < 0) % cannot have negative seeds!
                    % check if site is empty
                    if prePred < 1/2 && postPred < 1/2
                        % the site is empty before and after the visit
                        flagInt{nInt} = sprintf('Retrieval from empty site %d on interaction %d',...
                            siteNum, nInt);
                    else
                        % Unclear why this would happen, requires manual check
                        flagInt{nInt} = sprintf('Uninterpretable seed change on visit %d', nInt);
                    end
                elseif (postPred < 1/2) && (thisChange > 1/2)
                    % this site is not occupied post visit
                    flagInt{nInt} = sprintf('Cache to site %d on interaction %d is not confirmed in bot cam',...
                        siteNum, nInt);
                end
                
                % don't overwrite a flag from earlier
                if isempty(flagInt{nInt})
                    if ~postCheck
                        flagInt{nInt} = sprintf('Interaction %d to site %d has post-count %d but botCam predicts %0.2f',...
                            nInt, siteNum, postSeedCount, postPred);
                    elseif ~preCheck
                        flagInt{nInt} = sprintf('Interaction %d to site %d has pre-count %d but botCam predicts %0.2f',...
                            nInt, siteNum, preSeedCount, prePred);
                    end
                end
            end
            
            seedStruct.flagInt = flagInt;
            seedStruct.seedChanges = seedChanges;
            seedStruct.initSeedCounts = initSeedCounts;
            seedStruct.prePreds = double(prePreds);
            seedStruct.postPreds = double(postPreds);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, seedStruct, botVid, cacheNet, topVid)
            
            app.startTimer = tic;
            
            if nargin>2
                % create temporary video object for bottom camera
                botVidObj = VideoReader(botVid);
                % Run preprocessing and data loading functions
                seedStruct = app.getSeedTimes(seedStruct);
                seedStruct = app.processSiteInteractions(seedStruct);
                seedStruct = app.getStartingImages(seedStruct, botVidObj);
                fprintf('Parsing %d interactions and loading botCam Data \n', length(seedStruct.newCacheTimes))
                seedStruct = app.getInteractionImages(seedStruct, botVidObj);
                fprintf('Finished loading images in %d minutes \n', round(toc(app.startTimer)/60))
                seedStruct = app.processBotCamImgs(seedStruct, cacheNet);
                app.botVid = botVid;
                app.topVid = topVid;
                app.cacheNet = cacheNet;
            else
                fprintf('Recreating GUI from saved structure \n'),
            end
            
            % set initial properties
            app.seedStruct = seedStruct;
            app.nInteractions = size(seedStruct.seedChanges,1);
            app.nInt = 1;
            app.nSite = seedStruct.cacheNum(app.nInt);
            
            % set up visit slider
            app.InteractionSlider.Limits = [1, app.nInteractions];
            app.InteractionSlider.MajorTicks = 100:100:app.nInteractions;
            app.InteractionSlider.Value = 1;
            
            % set up flagMask array
            app.flagMask = false(app.nInteractions,1);
            
            % set up seedcount plot
            axis(app.seedCountAxes, 'tight');
            
            % make plots
            app.updatePlots,
        end

        % Value changed function: InteractionSlider
        function InteractionSliderValueChanged(app, event)
            if app.InteractionButton.Value
                app.InteractionSlider.Value = round(app.InteractionSlider.Value);
            elseif app.SiteButton.Value
                siteInteractions = find(app.seedStruct.cacheNum==app.nSite);
                [~,newInt] = min(abs(app.InteractionSlider.Value - siteInteractions));
                app.InteractionSlider.Value = siteInteractions(newInt);
            elseif app.FlagButton.Value
                allFlags = find(~cellfun(@isempty, app.seedStruct.flagInt));
                [~,nFlag] = min(abs(app.InteractionSlider.Value - allFlags));
                app.InteractionSlider.Value = allFlags(nFlag);
            end
            
            app.nInt = app.InteractionSlider.Value;
            app.nSite = app.seedStruct.cacheNum(app.nInt);
            app.updatePlots,
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = -event.VerticalScrollCount;
            if app.InteractionButton.Value
                newValue = round(app.InteractionSlider.Value + verticalScrollCount);
                app.InteractionSlider.Value = max(1, min(newValue, app.nInteractions));
            elseif app.SiteButton.Value
                siteInteractions = find(app.seedStruct.cacheNum==app.nSite);
                newInt = round(find(siteInteractions==app.nInt) + verticalScrollCount);
                newInt = max(1, min(newInt, length(siteInteractions)));
                app.InteractionSlider.Value = siteInteractions(newInt);
            elseif app.FlagButton.Value
                allFlags = find(~cellfun(@isempty, app.seedStruct.flagInt));
                if isempty(allFlags)
                    return
                end
                if verticalScrollCount > 0
                    nFlag = find(allFlags>app.nInt,1,'first');
                    if isempty(nFlag)
                        nFlag = length(allFlags);
                    end
                else
                    nFlag = find(allFlags<app.nInt,1,'last');
                    if isempty(nFlag)
                        nFlag = 1;
                    end
                end
                app.InteractionSlider.Value = allFlags(nFlag);
            end
            app.nInt = app.InteractionSlider.Value;
            app.nSite = app.seedStruct.cacheNum(app.nInt);
            app.updatePlots,
        end

        % Value changed function: SeedChangeSpinner
        function SeedChangeSpinnerValueChanged(app, event)
            value = app.SeedChangeSpinner.Value;
            app.seedStruct.seedChanges(app.nInt,app.nSite) = value;
            app.updateFlags,
            app.updatePlots,
        end

        % Value changed function: InitialCountSpinner
        function InitialCountSpinnerValueChanged(app, event)
            value = app.InitialCountSpinner.Value;
            app.seedStruct.initSeedCounts(app.nSite) = value;
            app.updateFlags,
            app.updatePlots,
        end

        % Button pushed function: ShowInitImagesButton
        function ShowInitImagesButtonPushed(app, event)
            figure,imshow(imresize(app.seedStruct.initImgs(:,:,app.nSite), 4)),
            title(sprintf('Initial Image for Perch %d', app.nSite)),
        end

        % Selection changed function: NavbyButtonGroup
        function NavbyButtonGroupSelectionChanged(app, event)
            app.updatePlots,            
        end

        % Image clicked function: botCamImage
        function botCamImageClicked(app, event)
            figure,imshow(imresize(cat(2, app.seedStruct.preIms(:,:,app.nInt), app.seedStruct.postIms(:,:,app.nInt)), 4)),
            title(sprintf('Pre- and Post-Images for Perch %d', app.nSite))
        end

        % Value changed function: PrePredSpinner
        function PrePredSpinnerValueChanged(app, event)
            % round value to 0 or 1 and replace prediction in seedStruct
            value = round(app.PrePredSpinner.Value); 
            app.seedStruct.prePreds(app.nInt) = value;
            
            % add this visit to list of re-labeled images
            app.newPrePreds = unique(cat(1,app.newPrePreds,app.nInt));
            app.updateFlags,
            app.updatePlots,
        end

        % Value changed function: PostPredSpinner
        function PostPredSpinnerValueChanged(app, event)
            % round value to 0 or 1 and replace prediction in seedStruct
            value = app.PostPredSpinner.Value; 
            app.seedStruct.postPreds(app.nInt) = value;
            
            % add this visit to list of re-labeled images
            app.newPostPreds = unique(cat(1,app.newPostPreds,app.nInt));
            app.updateFlags,
            app.updatePlots,
        end

        % Value changed function: MaskFlagsButton
        function MaskFlagsButtonValueChanged(app, event)
            value = app.MaskFlagsButton.Value;
            % Apply Mask Status change to all visits without changes in
            % seed count
            if app.MaskAllCheckBox.Value
                % find stretch of site interactions without seed changes
                siteInteractions = find(app.seedStruct.cacheNum==app.nSite);
                seedChangeInteractions = find(app.seedStruct.seedChanges(siteInteractions, app.nSite));
                iStart = find(siteInteractions(seedChangeInteractions)<=app.nInt,1,'last');
                iStart = seedChangeInteractions(iStart);
                iEnd = find(siteInteractions(seedChangeInteractions)>app.nInt,1,'first');
                iEnd = seedChangeInteractions(iEnd)-1;
                if isempty(iStart)
                    iStart = 1;
                end
                if isempty(iEnd)
                    iEnd = length(siteInteractions);
                end
                theseInteractions = siteInteractions(iStart:iEnd);
            else
                theseInteractions = app.nInt;
            end
            if value
                app.seedStruct.flagInt(theseInteractions) = cell(length(theseInteractions),1);
                app.flagMask(theseInteractions) = true;
                app.updateFlags,
                app.updatePlots,
            else
                app.flagMask(theseInteractions) = false;
                app.updateFlags,
                app.updatePlots,
            end
        end

        % Button pushed function: SaveAnnotatedDataButton
        function SaveAnnotatedDataButtonPushed(app, event)
            fn = fullfile(app.seedStruct.path, 'annotatedSeeds');
            annotatedSeeds = app.seedStruct;
            save(fn, 'annotatedSeeds')
            fprintf('Saved data to %s \n', fn),
        end

        % Button pushed function: DispTopVideoButton
        function DispTopVideoButtonPushed(app, event)
            topVidObj = VideoReader(app.topVid);
            thisTime = app.seedStruct.newCacheTimes(app.nInt)-15:app.seedStruct.endCacheTimes(app.nInt)+15;
            fprintf('Loading Video for interaction %d with duration %0.1f sec \n', app.nInt, length(thisTime)/50),
            thisMov = zeros(640,1896,length(thisTime),'uint8');
            topVidObj.CurrentTime = thisTime(1)/50;
            for f = 1:length(thisTime)
                tmp = topVidObj.readFrame;
%                 tmp = app.topVid.read(thisTime(f));
                thisMov(:,:,f) = tmp(:,:,1);
            end
            
            figure, %plot(thisTime-app.seedStruct.newCacheTimes(app.nInt),isnan(app.seedStruct.smSeed(thisTime)))
            plot(thisTime-app.seedStruct.newCacheTimes(app.nInt),app.seedStruct.smSeed(thisTime),'.-','LineWidth',1)
            ylim([-0.02,1.02])
            xlim(thisTime([1,end])-app.seedStruct.newCacheTimes(app.nInt))
            implay(thisMov),
        end

        % Button pushed function: DispBotVideoButton
        function DispBotVideoButtonPushed(app, event)
            botVidObj = VideoReader(app.botVid);
            thisTime = app.seedStruct.newCacheTimes(app.nInt)-15:app.seedStruct.endCacheTimes(app.nInt)+15;
            fprintf('Loading Video for interaction %d with duration %0.1f sec \n', app.nInt, length(thisTime)/50),
            thisTime = unique(round(thisTime/2)); % convert from topCamera to botCamera indices
            thisMov = zeros(31,31,length(thisTime),'uint8');
            botVidObj.CurrentTime = thisTime(1)/25;
            thisCent = round(app.seedStruct.cacheLoc(app.nSite,:));
            thisY = thisCent(2) + (-15:15);
            thisX = thisCent(1) + (-15:15);
            for f = 1:length(thisTime)
                tmp = botVidObj.readFrame;
                thisMov(:,:,f) = tmp(thisY,thisX,1);
            end
            implay(thisMov),
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key=="s"
                %set to site
                app.SiteButton.Value = true;
            elseif key=="f"
                %set to flag
                app.FlagButton.Value = true;
            elseif key=='i'
                %set to interaction
                app.InteractionButton.Value = true;
            elseif key=="n"
                % move to next "flagged" event and set to site mode
                allFlags = find(~cellfun(@isempty, app.seedStruct.flagInt));
                if ~isempty(allFlags)
                    app.InteractionSlider.Value = allFlags(1);
                    app.nInt = app.InteractionSlider.Value;
                    app.nSite = app.seedStruct.cacheNum(app.nInt); 
                else
                    warning('no flags remaining'),
                end
                app.SiteButton.Value = true;
            end
            app.updatePlots,
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.seedCountAxes = uiaxes(app.UIFigure);
            xlabel(app.seedCountAxes, 'Interaction #')
            ylabel(app.seedCountAxes, 'Seed Count')
            app.VisitSliderLabel = uilabel(app.UIFigure);
            app.VisitSliderLabel.Text = 'Visit #';
            app.InteractionSlider = uislider(app.UIFigure);
            app.InteractionSlider.ValueChangedFcn = createCallbackFcn(app, @InteractionSliderValueChanged, true);
            app.FlagLabel = uilabel(app.UIFigure);
            app.FlagLabel.Text = 'Flag: ';
            app.SeedChangeLabel = uilabel(app.UIFigure);
            app.SeedChangeLabel.Text = 'Seed Change:';
            app.botCamImage = uiimage(app.UIFigure);
            app.botCamImage.ImageClickedFcn = createCallbackFcn(app, @botCamImageClicked, true);
            app.NavbyButtonGroup = uibuttongroup(app.UIFigure);
            app.NavbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NavbyButtonGroupSelectionChanged, true);
            app.NavbyButtonGroup.Title = 'Nav by:';
            app.InteractionButton = uitogglebutton(app.NavbyButtonGroup);
            app.InteractionButton.Text = 'Interaction #';
            app.SiteButton = uitogglebutton(app.NavbyButtonGroup);
            app.SiteButton.Text = 'Site #';
            app.FlagButton = uitogglebutton(app.NavbyButtonGroup);
            app.FlagButton.Text = 'Flag #';
            app.FlagButton.Value = true;
            app.SeedChangeSpinnerLabel = uilabel(app.UIFigure);
            app.SeedChangeSpinnerLabel.Text = 'Seed Change:';
            app.SeedChangeSpinner = uispinner(app.UIFigure);
            app.SeedChangeSpinner.ValueChangedFcn = createCallbackFcn(app, @SeedChangeSpinnerValueChanged, true);
            app.InitialCountSpinnerLabel = uilabel(app.UIFigure);
            app.InitialCountSpinnerLabel.Text = 'Initial Count:';
            app.InitialCountSpinner = uispinner(app.UIFigure);
            app.InitialCountSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialCountSpinnerValueChanged, true);
            app.ShowInitImagesButton = uibutton(app.UIFigure, 'push');
            app.ShowInitImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInitImagesButtonPushed, true);
            app.ShowInitImagesButton.Text = 'Show Init Images';
            app.PrePredSpinnerLabel = uilabel(app.UIFigure);
            app.PrePredSpinnerLabel.Text = 'Pre- Pred: ';
            app.PrePredSpinner = uispinner(app.UIFigure);
            app.PrePredSpinner.Limits = [0 1];
            app.PrePredSpinner.ValueDisplayFormat = '%.2f';
            app.PrePredSpinner.ValueChangedFcn = createCallbackFcn(app, @PrePredSpinnerValueChanged, true);
            app.PostPredSpinnerLabel = uilabel(app.UIFigure);
            app.PostPredSpinnerLabel.Text = 'Post- Pred: ';
            app.PostPredSpinner = uispinner(app.UIFigure);
            app.PostPredSpinner.Limits = [0 1];
            app.PostPredSpinner.ValueDisplayFormat = '%.2f';
            app.PostPredSpinner.ValueChangedFcn = createCallbackFcn(app, @PostPredSpinnerValueChanged, true);
            app.MaskFlagsButton = uibutton(app.UIFigure, 'state');
            app.MaskFlagsButton.ValueChangedFcn = createCallbackFcn(app, @MaskFlagsButtonValueChanged, true);
            app.MaskFlagsButton.Text = 'Mask Flags';
            app.MaskAllCheckBox = uicheckbox(app.UIFigure);
            app.MaskAllCheckBox.Text = 'Mask All';
            app.SaveAnnotatedDataButton = uibutton(app.UIFigure, 'push');
            app.SaveAnnotatedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotatedDataButtonPushed, true);
            app.SaveAnnotatedDataButton.Text = 'Save Annotated Data';
            app.DispTopVideoButton = uibutton(app.UIFigure, 'push');
            app.DispTopVideoButton.ButtonPushedFcn = createCallbackFcn(app, @DispTopVideoButtonPushed, true);
            app.DispTopVideoButton.Text = 'Disp Top Video';
            app.DispBotVideoButton = uibutton(app.UIFigure, 'push');
            app.DispBotVideoButton.ButtonPushedFcn = createCallbackFcn(app, @DispBotVideoButtonPushed, true);
            app.DispBotVideoButton.Text = 'Disp Bot Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = siteInteractionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end